classdef ProjectPPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        hamlapEditField      matlab.ui.control.EditField
        HmlpEditFieldLabel   matlab.ui.control.Label
        bEditField           matlab.ui.control.EditField
        bEditFieldLabel      matlab.ui.control.Label
        TnhvvthButton        matlab.ui.control.Button
        solanlapEditField    matlab.ui.control.EditField
        SlnlpEditFieldLabel  matlab.ui.control.Label
        nghiemEditField      matlab.ui.control.EditField
        NghimEditFieldLabel  matlab.ui.control.Label
        chonppButtonGroup    matlab.ui.container.ButtonGroup
        tieptuyenButton      matlab.ui.control.RadioButton
        lapButton            matlab.ui.control.RadioButton
        chiadoiiButton       matlab.ui.control.RadioButton
        nhapsaisoEditField   matlab.ui.control.EditField
        SaisLabel            matlab.ui.control.Label
        nhapaEditField       matlab.ui.control.EditField
        aEditFieldLabel      matlab.ui.control.Label
        nhaphamEditField     matlab.ui.control.EditField
        HmfxEditFieldLabel   matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TnhvvthButton
        function TnhvvthButtonPushed(app, event)
         f=app.nhaphamEditField.Value;
            f = str2func(['@(x)',f]);
            x=linspace(1,10);
            ham=f(x);
            plot(app.UIAxes,x,ham);
        end

        % Selection changed function: chonppButtonGroup
        function chonppButtonGroupSelectionChanged(app, event)
            selectedButton = app.chonppButtonGroup.SelectedObject;
            if(app.lapButton.Value)
                app.hamlapEditField.Visible="on"
            else
                app.hamlapEditField.Visible="off"
            end
            selectedButton = app.phuongphap.SelectedObject;
            if (app.chiadoi.Value == 1)
                f = app.nhaphamEditField.Value;
                f = str2func(['@(x)',f]);
                a = app.nhapaEditField.Value;
                b = app.nhapbEditField.Value;
                saiso=app.nhapsaisoEditField.Value;
                [nghiem,solanlap] = chiadoi(f,a,b,saiso);
                app.nghiemEditField.Value  = num2str(nghiem);
                app.solanlapEditField.Value = num2str(solanlap);
            elseif (app.lap.Value == 1)
                f = app.fp.Value;
                f = str2func(['@(x)',f]);
                a = app.nhapaEditField.Value;
                b = app.nhapbEditField.Value;
                saiso=app.nhapsaisoEditField.Value;
                [nghiem,solanlap] = lap(f,a,b,saiso);
                app.nghiemEditField.Value  = num2str(nghiem);
                app.solanlapEditField.Value = num2str(solanlap);
            else (app.tieptuyen.Value == 1)
                f = app.nhaphamEditField.Value;
                f = str2func(['@(x)',f]);
                a = app.nhapaEditField.Value;
                b = app.nhapbEditField.Value;
                saiso=app.nhapsaisoEditField.Value;
                [nghiem,solanlap] = tieptuyen(f,a,b,saiso);
                app.nghiemEditField.Value  = num2str(nghiem);
                app.solanlapEditField.Value = num2str(solanlap);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HmfxEditFieldLabel = uilabel(app.UIFigure);
            app.HmfxEditFieldLabel.Text = 'Hàm f(x)';
            app.nhaphamEditField = uieditfield(app.UIFigure, 'text');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.nhapaEditField = uieditfield(app.UIFigure, 'text');
            app.SaisLabel = uilabel(app.UIFigure);
            app.SaisLabel.Text = 'Sai số';
            app.nhapsaisoEditField = uieditfield(app.UIFigure, 'text');
            app.chonppButtonGroup = uibuttongroup(app.UIFigure);
            app.chonppButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @chonppButtonGroupSelectionChanged, true);
            app.chonppButtonGroup.Title = 'Chọn phương pháp';
            app.chiadoiiButton = uiradiobutton(app.chonppButtonGroup);
            app.chiadoiiButton.Text = 'Chia đôi';
            app.chiadoiiButton.Value = true;
            app.lapButton = uiradiobutton(app.chonppButtonGroup);
            app.lapButton.Text = 'Lặp';
            app.tieptuyenButton = uiradiobutton(app.chonppButtonGroup);
            app.tieptuyenButton.Text = 'Tiếp tuyến';
            app.NghimEditFieldLabel = uilabel(app.UIFigure);
            app.NghimEditFieldLabel.Text = 'Nghiệm';
            app.nghiemEditField = uieditfield(app.UIFigure, 'text');
            app.SlnlpEditFieldLabel = uilabel(app.UIFigure);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.solanlapEditField = uieditfield(app.UIFigure, 'text');
            app.TnhvvthButton = uibutton(app.UIFigure, 'push');
            app.TnhvvthButton.ButtonPushedFcn = createCallbackFcn(app, @TnhvvthButtonPushed, true);
            app.TnhvvthButton.Text = 'Tính và vẽ đồ thị';
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'text');
            app.HmlpEditFieldLabel = uilabel(app.UIFigure);
            app.HmlpEditFieldLabel.Text = 'Hàm lặp';
            app.hamlapEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectPPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end